classdef HW2_M11415015 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        CutMenu           matlab.ui.container.Menu
        OpenMenu          matlab.ui.container.Menu
        SaveMenu          matlab.ui.container.Menu
        CloseMenu         matlab.ui.container.Menu
        ExitMenu          matlab.ui.container.Menu
        MergeMenu         matlab.ui.container.Menu
        OpenMenu_2        matlab.ui.container.Menu
        CloseMenu_2       matlab.ui.container.Menu
        ExitMenu_2        matlab.ui.container.Menu
        WatermarkMenu     matlab.ui.container.Menu
        OutputmergeLabel  matlab.ui.control.Label
        UITable           matlab.ui.control.Table
        Button_2          matlab.ui.control.StateButton
        Button            matlab.ui.control.StateButton
        RangeLabel        matlab.ui.control.Label
        RangeSlider       matlab.ui.control.RangeSlider
        SliderLabel       matlab.ui.control.Label
        LUTsListBox       matlab.ui.control.ListBox
        ListBoxLabel      matlab.ui.control.Label
        LUTsLabel         matlab.ui.control.Label
        Image2            matlab.ui.control.Image
        Image             matlab.ui.control.Image
    end

    
    properties (Access = private)
        v1          % VideoReader 物件
        im          % 當下影格
        lut         % 3D 色調對照表
        lutSize     % LUT 格數
        t           % 待合併表列
        watermark = {'NTUST 2026'; '20'}  % 浮水印預設參數
        watermarkImage  % 快取渲染好的浮水印圖
        summaryFrames   % 10格摘要影格（加分用）
        summaryDur      % 摘要對應的影片總長（加分用）
    end
    
    methods (Access = private)
        
        function [lut, lutSize] = readCubeFile(app, cubeFile)
            fid = fopen(cubeFile, 'r');
            lut = []; lutSize = 0;
            while ~feof(fid)
                line = fgetl(fid);
                if ~ischar(line), break; end
                line = strtrim(line);
                if startsWith(line, 'LUT_3D_SIZE')
                    lutSize = sscanf(line, 'LUT_3D_SIZE %d');
                elseif isempty(line) || startsWith(line, '#')
                    continue;
                else
                    values = sscanf(line, '%f %f %f');
                    if numel(values) == 3
                        lut = [lut; values'];
                    end
                end
            end                  % ← while 的 end
            fclose(fid);         % ← 移到 while 外面
            lut = reshape(lut, [lutSize, lutSize, lutSize, 3]);
        end
        
        function imgOut = applyLUT(app, img, lut, lutSize)
            img = (img(:,:,1:3));
            if ~isa(img, 'double')
                img = double(img) / 255;
            end
            imgScaled = (img) * (lutSize - 1) + 1;
            imgOut = zeros(size(img));
            for c = 1:3
                imgOut(:,:,c) = interp3( ...
                    1:lutSize, 1:lutSize, 1:lutSize, ...
                    lut(:,:,:,c), ...
                    imgScaled(:,:,2), imgScaled(:,:,1), imgScaled(:,:,3), ...
                    'linear', 0);
            end
            imgOut = max(0, min(1, imgOut));
        end
        function loadSelectedLUT(app)
            lutName = app.LUTsListBox.Value;
            if isempty(lutName), return; end
                cubeFile = fullfile('LUTs', lutName);
            try
                [app.lut, app.lutSize] = readCubeFile(app, cubeFile);
            catch
                uialert(app.UIFigure, '無法讀取 LUT 檔案', 'Error');
            end
        end
        function makeVideoSummary(app, vr, imgComp)
            dur = vr.Duration;
            times = linspace(0, dur, 10);
            frames = cell(1, 10);
            for i = 1:10
                vr.CurrentTime = min(times(i), dur - 0.001);
                frames{i} = readFrame(vr);
            end
            summary = cat(2, frames{:});
            imgComp.ImageSource = summary;
            app.summaryFrames = frames;
            app.summaryDur = dur;
        end
        function frame = addWatermark(app, frame)
            if isempty(app.watermark), return; end
            if isempty(app.watermarkImage)
                app.watermarkImage = renderWatermarkImage(app);
            end
            wm = double(app.watermarkImage) / 255;
            [wH, wW, ~] = size(wm);
            [fH, fW, ~] = size(frame);
        
            if wH > fH - 10 || wW > fW - 10, return; end
            frame(10:9+wH, 10:9+wW, :) = wm;
        end
        
        function wmImg = renderWatermarkImage(app)        % ← 新增這整個函式
            wmText = app.watermark{1};
            fontSize = str2double(app.watermark{2});
            if isnan(fontSize), fontSize = 20; end

            padding = 8;
            approxW = max(100, round(fontSize * 0.75 * strlength(wmText) + 2*padding));
            approxH = round(fontSize * 1.6 + 2*padding);

            fig = figure('Visible','off','Units','pixels', ...
                'Position',[0 0 approxW approxH], ...
                'Color','yellow','MenuBar','none','ToolBar','none');
            ax = axes(fig,'Units','normalized','Position',[0 0 1 1], ...
                'Color','yellow','XColor','none','YColor','none');
            xlim(ax,[0 1]); ylim(ax,[0 1]);
            text(ax, 0.5, 0.5, char(wmText), ...
                'FontSize', fontSize, ...
                'Color', 'black', ...
                'FontWeight', 'bold', ...
                'HorizontalAlignment','center', ...
                'VerticalAlignment','middle');
            drawnow;
            F = getframe(ax);
            close(fig);
            wmImg = F.cdata;
        end

        function mergeVideos(app)
            if height(app.t) == 0, return; end

            vOut = VideoWriter('merge.mp4', 'MPEG-4');
            firstVR = VideoReader(app.t.Filename(1));
            vOut.FrameRate = firstVR.FrameRate;
            open(vOut);

            for i = 1:height(app.t)
                vr = VideoReader(app.t.Filename(i));
                while hasFrame(vr)
                    writeVideo(vOut, readFrame(vr));
                end
            end
            close(vOut);
        
            app.OutputmergeLabel.Text = 'Output: merge.mp4';
        
            % 製作合併後視訊摘要
            vMerge = VideoReader('merge.mp4');
            makeVideoSummary(app, vMerge, app.Image2);
        end
        
        function highlightSummaryFrame(app, currentTime, totalDur, range)
            if isempty(app.summaryFrames), return; end
            if nargin < 4, range = [0, totalDur]; end
    
            summary = cat(2, app.summaryFrames{:});
            H = size(app.summaryFrames{1}, 1);
            W = size(app.summaryFrames{1}, 2);
            times = linspace(0, app.summaryDur, 10);
    
          % 範圍外的格子塗黑
            for i = 1:10
                if times(i) < range(1) || times(i) > range(2)
                    x1 = (i-1)*W + 1; x2 = i*W;
                    summary(:, x1:x2, :) = 0;
                end
            end
    
            % 當前播放位置畫黃框
            idx = round(currentTime / totalDur * 9) + 1;
            idx = max(1, min(10, idx));
            x1 = (idx-1)*W + 1;
            x2 = idx*W;
            thickness = 3;
            summary(1:thickness, x1:x2, 1) = 255;
            summary(1:thickness, x1:x2, 2) = 255;
            summary(1:thickness, x1:x2, 3) = 0;
            summary(H-thickness+1:H, x1:x2, 1) = 255;
            summary(H-thickness+1:H, x1:x2, 2) = 255;
            summary(H-thickness+1:H, x1:x2, 3) = 0;
            summary(1:H, x1:x1+thickness-1, 1) = 255;
            summary(1:H, x1:x1+thickness-1, 2) = 255;
            summary(1:H, x1:x1+thickness-1, 3) = 0;
            summary(1:H, x2-thickness+1:x2, 1) = 255;
            summary(1:H, x2-thickness+1:x2, 2) = 255;
            summary(1:H, x2-thickness+1:x2, 3) = 0;
    
            app.Image2.ImageSource = summary;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 初始化合併表列
            app.t = table('Size', [0, 2], ...
            'VariableTypes', {'string', 'double'}, ...
            'VariableNames', {'Filename', 'Duration'});

            % 載入 LUTs 目錄內容到清單
            if exist('LUTs', 'dir')
              files = dir('LUTs');
              names = {files.name};
              names = names(~ismember(names, {'.', '..'}));
              app.LUTsListBox.Items = names;
            end

              % 隱藏大部分元件
              app.Image.Visible = 'off';
              app.Image2.Visible = 'off';
              app.LUTsListBox.Visible = 'off';
              app.LUTsLabel.Visible = 'off';
              app.RangeSlider.Visible = 'off';
              app.RangeLabel.Visible = 'off';
              app.Button.Visible = 'off';
              app.Button_2.Visible = 'off';
              app.UITable.Visible = 'off';
              app.OutputmergeLabel.Visible = 'off';
              app.SliderLabel.Visible = 'off';
              app.ListBoxLabel.Visible = 'off';
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            % 開啟影片檔
            [file, path] = uigetfile({'*.mp4;*.avi;*.mov', 'Video Files'});
            if isequal(file, 0), return; end
        
            app.v1 = VideoReader(fullfile(path, file));
            % 顯示 Cut 相關元件，隱藏 Merge 元件
            app.Image.Visible = 'on';
            app.Image2.Visible = 'on';
            app.LUTsListBox.Visible = 'on';
            app.LUTsLabel.Visible = 'on';
            app.RangeSlider.Visible = 'on';
            app.RangeLabel.Visible = 'on';
            app.Button.Visible = 'on';
            app.Button_2.Visible = 'off';
            app.UITable.Visible = 'off';
            app.OutputmergeLabel.Visible = 'off';
        
            % 顯示第一影格
            app.v1.CurrentTime = 0;
            app.im = readFrame(app.v1);
            app.Image.ImageSource = app.im;
        
            % 設定 RangeSlider 範圍為影片總長
            dur = app.v1.Duration;
            app.RangeSlider.Limits = [0, dur];
            app.RangeSlider.Value = [0, dur];
        
            % 載入 LUT
            loadSelectedLUT(app);
        
            % 製作 10 格視訊摘要
            makeVideoSummary(app, app.v1, app.Image2);
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            if isempty(app.v1)
                uialert(app.UIFigure, '請先開啟影片', 'Error'); return;
            end

            [file, path] = uiputfile({'*.mp4', 'MP4 Video'});
            if isequal(file, 0), return; end
        
            range = app.RangeSlider.Value;
            vOut = VideoWriter(fullfile(path, file), 'MPEG-4');
            vOut.FrameRate = app.v1.FrameRate;
            if isempty(app.lut)
                uialert(app.UIFigure, '請先選擇 LUT', 'Error');
                return;
            end
            open(vOut);
        
            app.v1.CurrentTime = range(1);
            fadeDur = 0.5;  % 加分：Fade In/Out 時長

            while hasFrame(app.v1) && app.v1.CurrentTime <= range(2)
                t_curr = app.v1.CurrentTime;
                frame = double(readFrame(app.v1)) / 255;
                frame = applyLUT(app, frame, app.lut, app.lutSize);
    
                % Fade In
                if t_curr - range(1) < fadeDur
                    frame = frame * ((t_curr - range(1)) / fadeDur);
                end
                % Fade Out
                if range(2) - t_curr < fadeDur
                    frame = frame * ((range(2) - t_curr) / fadeDur);
                end
    
                frame = addWatermark(app, frame);
                writeVideo(vOut, uint8(frame * 255));
            end

            close(vOut);
            uialert(app.UIFigure, '影片儲存完成！', 'Done');
        end

        % Menu selected function: CloseMenu
        function CloseMenuSelected(app, event)
            app.v1 = [];
            app.im = [];
            app.lut = [];
            app.lutSize = [];
            app.summaryFrames = [];
            startupFcn(app);
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            delete(app);
        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            % 按鈕彈起時停止播放
            if ~app.Button.Value
                app.Button.Text = 'Play';
                return;     
            end
            % 防呆：未開影片
            if isempty(app.v1)
                app.Button.Value = false;
                uialert(app.UIFigure, '請先開啟影片', 'Error');
                return;
            end
            
            % 防呆：未載入 LUT
            if isempty(app.lut)
                app.Button.Value = false;
                uialert(app.UIFigure, '請先選擇 LUT', 'Error');
                return;
            end
            
            range = app.RangeSlider.Value;
            app.v1.CurrentTime = range(1);
            app.RangeSlider.Enable = 'off';  % 播放中鎖定滑桿
            
            % 播放前先對 Image2 套黑框
            if ~isempty(app.summaryFrames)
                times = linspace(0, app.summaryDur, 10);
                summary = cat(2, app.summaryFrames{:});
                for i = 1:10
                    if times(i) < range(1) || times(i) > range(2)
                        W = size(app.summaryFrames{i}, 2);
                        x1 = (i-1)*W + 1; x2 = i*W;
                        summary(:, x1:x2, :) = 0;
                    end
                end
                app.Image2.ImageSource = summary;
            end

            while app.Button.Value && hasFrame(app.v1) && app.v1.CurrentTime <= range(2)
                frame = double(readFrame(app.v1)) / 255;
                frame = applyLUT(app, frame, app.lut, app.lutSize);
                frame = addWatermark(app, frame);
                app.Image.ImageSource = uint8(frame * 255);
                app.Button.Text = num2str(app.v1.CurrentTime, '%.2f');
            
                % 加分：同步標記 Image2 黃框
                highlightSummaryFrame(app, app.v1.CurrentTime, app.v1.Duration, range);
                drawnow;
            end
            
            % 播放結束，還原狀態
            app.Button.Value = false;
            app.Button.Text = 'Play';
            app.RangeSlider.Enable = 'on';      
            
        end

        % Value changed function: LUTsListBox
        function LUTsListBoxValueChanged(app, event)
            % 載入新選取的 LUT
            loadSelectedLUT(app);

            % 即時預覽套用 LUT 後的效果
            if ~isempty(app.im) && ~isempty(app.lut)
                preview = applyLUT(app, app.im, app.lut, app.lutSize);
                preview = addWatermark(app, preview);
                app.Image.ImageSource = uint8(preview * 255);
            end    
        end

        % Menu selected function: WatermarkMenu
        function WatermarkMenuSelected(app, event)
            answer = inputdlg({'Watermark', 'FontSize'}, 'Watermark', 1, app.watermark);
            if isempty(answer), return; end
            app.watermark = answer;
            app.watermarkImage = [];  % 清快取讓下次重新渲染
            % 即時預覽浮水印
            if ~isempty(app.im)
                frame = double(app.im) / 255;
                if ~isempty(app.lut)
                    frame = applyLUT(app, frame, app.lut, app.lutSize);
                end
                frame = addWatermark(app, frame);
                app.Image.ImageSource = uint8(frame * 255);
            end
        end

        % Menu selected function: OpenMenu_2
        function OpenMenu_2Selected(app, event)
            [file, path] = uigetfile({'*.mp4;*.avi;*.mov', 'Video Files'}, ...
            'Select Video', 'MultiSelect', 'on');
            if isequal(file, 0), return; end
            if ischar(file), file = {file}; end  % 單選時轉成 cell
             
            % 顯示 Merge 相關元件
            app.Image.Visible = 'on';
            app.Image2.Visible = 'on';
            app.UITable.Visible = 'on';
            app.Button_2.Visible = 'on';
            app.OutputmergeLabel.Visible = 'on';
            app.RangeSlider.Visible = 'off';
            app.RangeLabel.Visible = 'off';
            app.Button.Visible = 'off';
            app.LUTsListBox.Visible = 'off';
            app.LUTsLabel.Visible = 'off';
        
            % 將選取的影片加入表列
            for i = 1:numel(file)
                vr = VideoReader(fullfile(path, file{i}));
                newRow = {string(fullfile(path, file{i})), vr.Duration};
                app.t = [app.t; newRow];
            end
            app.UITable.Data = app.t;
        
            % 串接影片並更新摘要
            mergeVideos(app);
        end

        % Menu selected function: CloseMenu_2
        function CloseMenu_2Selected(app, event)
            app.t = table('Size', [0,2], ...
            'VariableTypes', {'string','double'}, ...
            'VariableNames', {'Filename','Duration'});
            startupFcn(app);
        end

        % Menu selected function: ExitMenu_2
        function ExitMenu_2Selected(app, event)
            delete(app);
        end

        % Value changed function: Button_2
        function Button_2ValueChanged(app, event)
            if ~app.Button_2.Value
                app.Button_2.Text = 'Play';
                return;
            end
 
            % 防呆：merge.mp4 不存在
            if ~exist('merge.mp4', 'file')
                app.Button_2.Value = false;
                uialert(app.UIFigure, '尚未產生 merge.mp4', 'Error');
                return;
            end

            vMerge = VideoReader('merge.mp4');

            while app.Button_2.Value && hasFrame(vMerge)
                frame = readFrame(vMerge);
                app.Image.ImageSource = frame;
                app.Button_2.Text = num2str(vMerge.CurrentTime, '%.2f');
      
                % 加分：同步標記 Image2 黃框
                highlightSummaryFrame(app, vMerge.CurrentTime, vMerge.Duration);
                drawnow;
            end
    
            app.Button_2.Value = false;
            app.Button_2.Text = 'Play';
            
        end

        % Value changed function: RangeSlider
        function RangeSliderValueChanged(app, event)
            if isempty(app.v1), return; end
            range = app.RangeSlider.Value;
            app.v1.CurrentTime = max(0, range(1));
            if hasFrame(app.v1)
                frame = double(readFrame(app.v1)) / 255;
                if ~isempty(app.lut)
                    frame = applyLUT(app, frame, app.lut, app.lutSize);
                end
                app.Image.ImageSource = uint8(frame * 255);
            end
            % Image2：範圍外的格子變黑
            if ~isempty(app.summaryFrames)
                times = linspace(0, app.summaryDur, 10);
                summary = cat(2, app.summaryFrames{:});
                for i = 1:10
                    if times(i) < range(1) || times(i) > range(2)
                        W = size(app.summaryFrames{i}, 2);
                        x1 = (i-1)*W + 1;
                        x2 = i*W;
                        summary(:, x1:x2, :) = 0;
                    end
                end
                app.Image2.ImageSource = summary;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CutMenu = uimenu(app.UIFigure);
            app.CutMenu.Text = 'Cut';
            app.OpenMenu = uimenu(app.CutMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.CutMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.CloseMenu = uimenu(app.CutMenu);
            app.CloseMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseMenuSelected, true);
            app.CloseMenu.Text = 'Close';
            app.ExitMenu = uimenu(app.CutMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.MergeMenu = uimenu(app.UIFigure);
            app.MergeMenu.Text = 'Merge';
            app.OpenMenu_2 = uimenu(app.MergeMenu);
            app.OpenMenu_2.MenuSelectedFcn = createCallbackFcn(app, @OpenMenu_2Selected, true);
            app.OpenMenu_2.Text = 'Open';
            app.CloseMenu_2 = uimenu(app.MergeMenu);
            app.CloseMenu_2.MenuSelectedFcn = createCallbackFcn(app, @CloseMenu_2Selected, true);
            app.CloseMenu_2.Text = 'Close';
            app.ExitMenu_2 = uimenu(app.MergeMenu);
            app.ExitMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ExitMenu_2Selected, true);
            app.ExitMenu_2.Text = 'Exit';
            app.WatermarkMenu = uimenu(app.UIFigure);
            app.WatermarkMenu.MenuSelectedFcn = createCallbackFcn(app, @WatermarkMenuSelected, true);
            app.WatermarkMenu.Text = 'Watermark';
            app.Image = uiimage(app.UIFigure);
            app.Image2 = uiimage(app.UIFigure);
            app.LUTsLabel = uilabel(app.UIFigure);
            app.LUTsLabel.Text = 'LUTs';
            app.ListBoxLabel = uilabel(app.UIFigure);
            app.ListBoxLabel.Text = 'List Box';
            app.LUTsListBox = uilistbox(app.UIFigure);
            app.LUTsListBox.ValueChangedFcn = createCallbackFcn(app, @LUTsListBoxValueChanged, true);
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.RangeSlider = uislider(app.UIFigure, 'range');
            app.RangeSlider.ValueChangedFcn = createCallbackFcn(app, @RangeSliderValueChanged, true);
            app.RangeLabel = uilabel(app.UIFigure);
            app.RangeLabel.Text = 'Range';
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Text = 'Play';
            app.Button_2 = uibutton(app.UIFigure, 'state');
            app.Button_2.ValueChangedFcn = createCallbackFcn(app, @Button_2ValueChanged, true);
            app.Button_2.Text = 'Play';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Filename'; 'Duration'};
            app.UITable.RowName = {};
            app.OutputmergeLabel = uilabel(app.UIFigure);
            app.OutputmergeLabel.Text = 'Output: merge.mp4';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HW2_M11415015

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end